classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        B4Button  matlab.ui.control.Button
        A4Button  matlab.ui.control.Button
        G4Button  matlab.ui.control.Button
        F4Button  matlab.ui.control.Button
        E4Button  matlab.ui.control.Button
        D4Button  matlab.ui.control.Button
        C4Button  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)


            % Ange frekvensen för C-tonen (261.63 Hz för C4)
            frekvens = 261.63;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;
            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för C-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            % Ange frekvensen för D-tonen (293.66 Hz för D4)
            frekvens = 293.66;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för D-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Callback function: not associated with a component
        function UIFigureKeyRelease(app, event)

        end

        % Callback function: not associated with a component
        function UIFigureKeyRelease2(app, event)

        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            % Ange frekvensen för E-tonen (329.63 Hz för E4)
            frekvens = 329.63;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;
            
            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för E-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            % Ange frekvensen för F-tonen (349.23 Hz för F4)
            frekvens = 349.23;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35; 

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för F-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            % Ange frekvensen för G-tonen (392.00 Hz för G4)
            frekvens = 392.00;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för G-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            % Ange frekvensen för A-tonen (440.00 Hz för A4)
            frekvens = 440.00;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för A-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            % Ange frekvensen för B-tonen (493.88 Hz för B4)
            frekvens = 493.88;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för B-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end